classdef appalmost < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Shrunk                   matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        ResizeTab                matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        CommandCenterPanel       matlab.ui.container.Panel
        UploadButton             matlab.ui.control.Button
        WidthEditFieldLabel      matlab.ui.control.Label
        WidthEditField           matlab.ui.control.NumericEditField
        HeightEditFieldLabel     matlab.ui.control.Label
        HeightEditField          matlab.ui.control.NumericEditField
        ReSizeButton             matlab.ui.control.Button
        imageSizeTextAreaLabel   matlab.ui.control.Label
        imageSizeTextArea        matlab.ui.control.TextArea
        OutputSizeTextAreaLabel  matlab.ui.control.Label
        OutputSizeTextArea       matlab.ui.control.TextArea
        ReSizeButton_2           matlab.ui.control.Button
        EnergyE1FnLabel          matlab.ui.control.Label
        EnergyEHogFnLabel        matlab.ui.control.Label
        SaveasButton             matlab.ui.control.Button
        EnergyfnTab              matlab.ui.container.Tab
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes2_2                matlab.ui.control.UIAxes
        CommandCenterPanel_2     matlab.ui.container.Panel
        UploadButton_2           matlab.ui.control.Button
        EnergyE1Button           matlab.ui.control.Button
        EnergyHOGButton          matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        GuideTab                 matlab.ui.container.Tab
        TextAreaLabel            matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: EnergyE1Button
        function EnergyE1ButtonPushed(app, event)
            import tools.read_images;
            import energy_funcs.e1_error;
            import energy_funcs.hog;
            import seam.*;
            import seam.create_hseams;
            import seam.create_vseams;
            import seam.seams_to_mnarrays;
            import add.add_horizontal_seams;
            import add.add_vertical_seams;
             
            
            global im2
            global energypic
             
              energy_e1 = e1_error(im2);
             
           imshow( energy_e1,'parent',app.UIAxes2_2);
                     axis(app.UIAxes2_2,'off');
            energypic = energy_e1;
        end

        % Button pushed function: EnergyHOGButton
        function EnergyHOGButtonPushed(app, event)
            import tools.read_images;
            import energy_funcs.e1_error;
            import energy_funcs.hog;
            import seam.*;
             
            
            global im2
            
             global energypic
             
              x =  hog(im2);
              
            imshow(x,'parent',app.UIAxes2_2);
              axis(app.UIAxes,'off');
            
            energypic = x;
        end

        % Button pushed function: ReSizeButton
        function ReSizeButtonPushed(app, event)
            import tools.read_images;
            import energy_funcs.*;
            import seam.*;
             
            import img.shrink;
            import img.grow;
             
        
            global im
            
            [rows columns numberOfColorChannels] = size(im);
           
            global modified
            
              energyselector =  @energy_funcs.e1_error;
            
            
            width = app.WidthEditField.Value;
            hight = app.HeightEditField.Value;
            
            if rows <= width && columns <= hight
                  
                  img_big = grow(im, energyselector,width  , hight );
                   image(im_big,'parent',app.UIAxes_2);
                      axis(app.UIAxes,'on');
                  modified = img_big;
                  
                  imagesizebig = size(img_big);
                  szsbig = num2str(imagesizebig);
            
                  app.OutputSizeTextArea.Value = szsbig;
            else
                  img_small = shrink(im, energyselector,width,hight);
                   image(img_small,'parent',app.UIAxes_2);
                      axis(app.UIAxes,'on');
                  imagesizesmall = size(img_small);
                  szsmall = num2str(imagesizesmall);
                  
                  modified = img_small;
                  
                  app.OutputSizeTextArea.Value = szsmall;
                  
            end


        end

        % Button pushed function: ReSizeButton_2
        function ReSizeButton_2Pushed(app, event)
              import tools.read_images;
            import energy_funcs.*;
            import seam.*;
             
            import img.shrink;
            import img.grow;
             
        
            global im
            
            [rows_old columns_old numberOfColorChannels] = size(im);
           
            global modified
            
              energyselector =  @energy_funcs.hog;


            colm_new = app.WidthEditField.Value;
            rows_new = app.HeightEditField.Value;


               rooo = rows_old - rows_new;
               
               cooo = columns_old - colm_new;
            
               
               if  rooo <= 0 && cooo <=  0
                  
                  img_big = grow(im, energyselector, rows_new , colm_new );
                  
                  image(img_big,'parent',app.UIAxes_2);
                      axis(app.UIAxes,'on');
                  
                  imagesizebig = size(img_big);
                  szsbig = num2str(imagesizebig);
            
                  app.OutputSizeTextArea.Value = szsbig;
            
                modified  = img_big;
              
               elseif rooo< 0 && cooo >0
              
                    img_big = grow(im, energyselector,  rows_new , columns_old);
                    
                img_small = shrink(img_big, energyselector,rows_new,colm_new);
                   image(img_small,'parent',app.UIAxes_2);
                      axis(app.UIAxes,'on');
                  
                  imagesizesmall = size(img_small);
                  szsmall = num2str(imagesizesmall);
            
                  app.OutputSizeTextArea.Value = szsmall;
                  modified = img_small;
               elseif rooo >= 0 && cooo >=0
                       
                 img_small = shrink(im, energyselector,rows_new,colm_new);
                   image(img_small,'parent',app.UIAxes_2);
                      axis(app.UIAxes,'on');
                  
                  imagesizesmall = size(img_small);
                  szsmall = num2str(imagesizesmall);
            
                  app.OutputSizeTextArea.Value = szsmall;
                  modified = img_small;
               elseif rooo > 0  && cooo < 0
                   
                    img_big = grow(im, energyselector, rows_old , colm_new );
                       
                img_small = shrink(img_big, energyselector,rows_new,colm_new);
                   image(img_small,'parent',app.UIAxes_2);
                      axis(app.UIAxes,'on');
                  
                  imagesizesmall = size(img_small);
                  szsmall = num2str(imagesizesmall);
            
                  app.OutputSizeTextArea.Value = szsmall;
                  modified = img_small;
                   
               end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
               global energypic
              
              [file,path] = uiputfile('*.jpg','save image As');
              
              app.Shrunk.Visible = 'off';
              app.Shrunk.Visible = 'on';
              imwrite( energypic, fullfile(path, file));
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
             global modified
              
              [file,path] = uiputfile('*.jpg','save image As');
                app.Shrunk.Visible = 'off';
                 app.Shrunk.Visible = 'on';
              imwrite( modified, fullfile(path, file));
        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
             
            path_to_folder = '../data/'; 
            
            [filename, pathname] = uigetfile('*.jpg');
                 app.Shrunk.Visible = 'off';
                 app.Shrunk.Visible = 'on';


            if ~ischar(filename)
                disp('User canceled uploading');


                return;


            end
            global im2
            
            im2=imread([pathname, filename]);
            
            imshow(im2,'Parent',app.UIAxes2);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            
        end

        % Button pushed function: UploadButton
        function upload(app, event)


            [filename, pathname] = uigetfile('*.jpg');
            
            global im
            
            
                 app.Shrunk.Visible = 'off';
                 app.Shrunk.Visible = 'on';
            
            
            if ~ischar(filename)
                disp('User canceled uploading');


                return;


            end
                    im=imread([pathname, filename]);
            
                  %  imshow(im,'parent',app.UIAxes);
                    
                     image(im,'parent',app.UIAxes);
                     axis(app.UIAxes,'on');
                   
                     imagesize = size(im);
                    sz = num2str(imagesize);
            
                    app.imageSizeTextArea.Value = sz;


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Shrunk = uifigure;
            app.Shrunk.Name = 'Shrunk';
            app.TabGroup = uitabgroup(app.Shrunk);
            app.ResizeTab = uitab(app.TabGroup);
            app.ResizeTab.Title = 'Resize';
            app.UIAxes = uiaxes(app.ResizeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.ResizeTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.CommandCenterPanel = uipanel(app.ResizeTab);
            app.CommandCenterPanel.Title = 'Command Center';
            app.UploadButton = uibutton(app.CommandCenterPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @upload, true);
            app.UploadButton.Text = 'Upload';
            app.WidthEditFieldLabel = uilabel(app.CommandCenterPanel);
            app.WidthEditFieldLabel.Text = ' Width';
            app.WidthEditField = uieditfield(app.CommandCenterPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Limits = [-1 Inf];
            app.HeightEditFieldLabel = uilabel(app.CommandCenterPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.CommandCenterPanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.ReSizeButton = uibutton(app.CommandCenterPanel, 'push');
            app.ReSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ReSizeButtonPushed, true);
            app.ReSizeButton.Text = 'ReSize';
            app.imageSizeTextAreaLabel = uilabel(app.CommandCenterPanel);
            app.imageSizeTextAreaLabel.Text = 'image Size';
            app.imageSizeTextArea = uitextarea(app.CommandCenterPanel);
            app.OutputSizeTextAreaLabel = uilabel(app.CommandCenterPanel);
            app.OutputSizeTextAreaLabel.Text = 'Output Size';
            app.OutputSizeTextArea = uitextarea(app.CommandCenterPanel);
            app.ReSizeButton_2 = uibutton(app.CommandCenterPanel, 'push');
            app.ReSizeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReSizeButton_2Pushed, true);
            app.ReSizeButton_2.Text = 'ReSize';
            app.EnergyE1FnLabel = uilabel(app.CommandCenterPanel);
            app.EnergyE1FnLabel.Text = ' Energy E1 Fn.';
            app.EnergyEHogFnLabel = uilabel(app.CommandCenterPanel);
            app.EnergyEHogFnLabel.Text = ' Energy EHog Fn.';
            app.SaveasButton = uibutton(app.CommandCenterPanel, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.EnergyfnTab = uitab(app.TabGroup);
            app.EnergyfnTab.Title = 'Energy fn';
            app.UIAxes2 = uiaxes(app.EnergyfnTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.EnergyfnTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.CommandCenterPanel_2 = uipanel(app.EnergyfnTab);
            app.CommandCenterPanel_2.Title = 'Command Center';
            app.UploadButton_2 = uibutton(app.CommandCenterPanel_2, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload';
            app.EnergyE1Button = uibutton(app.CommandCenterPanel_2, 'push');
            app.EnergyE1Button.ButtonPushedFcn = createCallbackFcn(app, @EnergyE1ButtonPushed, true);
            app.EnergyE1Button.Text = 'Energy E1';
            app.EnergyHOGButton = uibutton(app.CommandCenterPanel_2, 'push');
            app.EnergyHOGButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyHOGButtonPushed, true);
            app.EnergyHOGButton.Text = 'Energy HOG';
            app.SaveButton = uibutton(app.CommandCenterPanel_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GuideTab = uitab(app.TabGroup);
            app.GuideTab.Title = 'Guide';
            app.TextAreaLabel = uilabel(app.GuideTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.GuideTab);
            app.TextArea.Value = {'How to USE?'; ''; '1) You have to Click on "Upload" button first '; ''; '2) Choose any photo you like to resize using seam curving '; ''; '3) Then Choose your Desired OUTPUT height and width '; ''; '4) Choose any of the resizing methods, and wait!!'; ''; '5) After image is shown and output image size appears, you can save the image.'; ''; ''; 'Created by:'; ''; 'Oleh Kozynets, Brianna Burton , Karim Botros'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = appalmost

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Shrunk)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Shrunk)
        end
    end
end