classdef apptrial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        ResizeTab             matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        CommandCenterPanel    matlab.ui.container.Panel
        UploadButton          matlab.ui.control.Button
        WidthEditFieldLabel   matlab.ui.control.Label
        WidthEditField        matlab.ui.control.NumericEditField
        HightEditFieldLabel   matlab.ui.control.Label
        HightEditField        matlab.ui.control.NumericEditField
        EnergyfnTab           matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes2_2             matlab.ui.control.UIAxes
        CommandCenterPanel_2  matlab.ui.container.Panel
        UploadButton_2        matlab.ui.control.Button
        EnergyE1Button        matlab.ui.control.Button
        EnergyHOGButton       matlab.ui.control.Button
        Tab3                  matlab.ui.container.Tab
    end

    methods (Access = private)

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            
        end

        % Button pushed function: UploadButton
        function upload(app, event)
            [filename, pathname] = uigetfile('*.jpg');
                 
            global im
            
            im=imread([pathname, filename]);
            
            imshow(im,'Parent',app.UIAxes);
        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
             
            path_to_folder = '../data/'; 
            
            [filename, pathname] = uigetfile('*.jpg');
                 
            global im2
            
            im2=imread([pathname, filename]);
            
            imshow(im2,'Parent',app.UIAxes2);
        end

        % Button pushed function: EnergyE1Button
        function EnergyE1ButtonPushed(app, event)
            import tools.read_images;
            import energy_funcs.e1_error;
            import energy_funcs.hog_feature_vector;
            import seam.*;
             
            
            global im2
             
              energy_e1 = e1_error(im2);
             
            imshow(energy_e1,'Parent',app.UIAxes2_2);
        end

        % Button pushed function: EnergyHOGButton
        function EnergyHOGButtonPushed(app, event)
            import tools.read_images;
            import energy_funcs.e1_error;
            import energy_funcs.hog;
            import seam.*;
             
            
            global im2
             
             
               x =  hog(im2);
            imshow(x,'Parent',app.UIAxes2_2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResizeTab = uitab(app.TabGroup);
            app.ResizeTab.Title = 'Resize';
            app.UIAxes = uiaxes(app.ResizeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.ResizeTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.CommandCenterPanel = uipanel(app.ResizeTab);
            app.CommandCenterPanel.Title = 'Command Center';
            app.UploadButton = uibutton(app.CommandCenterPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @upload, true);
            app.UploadButton.Text = 'Upload';
            app.WidthEditFieldLabel = uilabel(app.CommandCenterPanel);
            app.WidthEditFieldLabel.Text = ' Width';
            app.WidthEditField = uieditfield(app.CommandCenterPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Limits = [-1 Inf];
            app.HightEditFieldLabel = uilabel(app.CommandCenterPanel);
            app.HightEditFieldLabel.Text = 'Hight';
            app.HightEditField = uieditfield(app.CommandCenterPanel, 'numeric');
            app.HightEditField.Limits = [0 Inf];
            app.EnergyfnTab = uitab(app.TabGroup);
            app.EnergyfnTab.Title = 'Energy fn';
            app.UIAxes2 = uiaxes(app.EnergyfnTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.EnergyfnTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.CommandCenterPanel_2 = uipanel(app.EnergyfnTab);
            app.CommandCenterPanel_2.Title = 'Command Center';
            app.UploadButton_2 = uibutton(app.CommandCenterPanel_2, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload';
            app.EnergyE1Button = uibutton(app.CommandCenterPanel_2, 'push');
            app.EnergyE1Button.ButtonPushedFcn = createCallbackFcn(app, @EnergyE1ButtonPushed, true);
            app.EnergyE1Button.Text = 'Energy E1';
            app.EnergyHOGButton = uibutton(app.CommandCenterPanel_2, 'push');
            app.EnergyHOGButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyHOGButtonPushed, true);
            app.EnergyHOGButton.Text = 'Energy HOG';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = apptrial

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end